classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        TransformationDropDownLabel  matlab.ui.control.Label
        TransformationDropDown       matlab.ui.control.DropDown
        SEPDropDownLabel             matlab.ui.control.Label
        SEPDropDown                  matlab.ui.control.DropDown
    end


    properties (Access = private)
        sensors(271,3) = zeros(271,3);
        measurements(271,360) = zeros(271,360); % measurements of MEG coils
        X(3,360) = zeros(3,360); % 3 chanels around the 3a,3b,1 area
        L(271,227871) = zeros(271,227871);
%        reconstructions() = [];
        Plot = ''; % Handle to plot
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         load('zef.mat','zef');
         app.sensors = zef.sensors(:,1:3);
         app.measurements = zef.measurements;
        
        index1 = find(zef.sensors(:,1)>25);
        index2 = find(zef.sensors(:,1)<50);
        index3 = find(zef.sensors(:,1)>0);
        index4 = find(zef.sensors(:,1)<70);
%         for i = 1:length(index2)
%             index5(i,:)=find(index1==index2(i));
%         end
%         
%         for i = 1:length(index2) 
%             index6(i,:)=find(index3==index4(i));
%         end
%  
%         for j = 1:length(index2) 
%             index7(i,:)=find(index5==index6(i));
%         end
% 
%         for i = 1:length(index2)
%             app.X(i,:) = zef.measurements(i,:);
%         end
          app.Plot = plot(app.UIAxes,1:5,1:5,'o')
          %app.Plot = plot(app.UIAxes,app.sensors(:,1),app.sensors(:,2),'o');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Preprocessing')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.ZTick = [0 0.5 1];
            app.TransformationDropDownLabel = uilabel(app.UIFigure);
            app.TransformationDropDownLabel.Text = 'Transformation';
            app.TransformationDropDown = uidropdown(app.UIFigure);
            app.TransformationDropDown.Items = {'Z-score', 'Logarithm'};
            app.TransformationDropDown.Value = 'Z-score';
            app.SEPDropDownLabel = uilabel(app.UIFigure);
            app.SEPDropDownLabel.Text = 'SEP';
            app.SEPDropDown = uidropdown(app.UIFigure);
            app.SEPDropDown.Items = {'channel 6', 'channel 7', 'channel 11', 'all channel with GLMP'};
            app.SEPDropDown.Value = 'channel 6';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end